classdef exp1ysml < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        Label                 matlab.ui.control.Label
        TextArea              matlab.ui.control.TextArea
        Button                matlab.ui.control.Button
        mmEditFieldLabel      matlab.ui.control.Label
        mmEditField           matlab.ui.control.NumericEditField
        mmEditField_2Label    matlab.ui.control.Label
        mmEditField_2         matlab.ui.control.NumericEditField
        gEditFieldLabel       matlab.ui.control.Label
        gEditField            matlab.ui.control.NumericEditField
        Label_2               matlab.ui.control.Label
        EditField             matlab.ui.control.NumericEditField
        x1x2xnxmmLabel        matlab.ui.control.Label
        x1x2xnxmmEditField    matlab.ui.control.NumericEditField
        f1f2fnfHzLabel        matlab.ui.control.Label
        f1f2fnfHzEditField    matlab.ui.control.NumericEditField
        x1x2xnxmmEditField_2  matlab.ui.control.NumericEditField
        x1x2xnxmmEditField_3  matlab.ui.control.NumericEditField
        x1x2xnxmmEditField_4  matlab.ui.control.NumericEditField
        x1x2xnxmmEditField_5  matlab.ui.control.NumericEditField
        x1x2xnxmmEditField_6  matlab.ui.control.NumericEditField
        f1f2fnfHzEditField_2  matlab.ui.control.NumericEditField
        f1f2fnfHzEditField_3  matlab.ui.control.NumericEditField
        f1f2fnfHzEditField_4  matlab.ui.control.NumericEditField
        f1f2fnfHzEditField_5  matlab.ui.control.NumericEditField
        f1f2fnfHzEditField_6  matlab.ui.control.NumericEditField
        Label_3               matlab.ui.control.Label
        TextArea_2            matlab.ui.control.TextArea
        Label_4               matlab.ui.control.Label
        EditField_2           matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: mmEditField
        function mmEditFieldValueChanged(app, event)
            l = app.mmEditField.Value;
        end

        % Value changed function: mmEditField_2
        function mmEditField_2ValueChanged(app, event)
            d = app.mmEditField_2.Value;
        end

        % Value changed function: gEditField
        function gEditFieldValueChanged(app, event)
            m = app.gEditField.Value; 
        end

        % Value changed function: x1x2xnxmmEditField
        function x1x2xnxmmEditFieldValueChanged(app, event)
            x = app.x1x2xnxmmEditField.Value;
        end

        % Value changed function: f1f2fnfHzEditField
        function f1f2fnfHzEditFieldValueChanged(app, event)
            f = app.f1f2fnfHzEditField.Value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            l = app.mmEditField.Value;
            d = app.mmEditField_2.Value;
            m = app.gEditField.Value; 
                     x(1) = app.x1x2xnxmmEditField.Value;
                     x(2) = app.x1x2xnxmmEditField_2.Value;
                     x(3) = app.x1x2xnxmmEditField_3.Value;
                     x(4) = app.x1x2xnxmmEditField_4.Value;
                     x(5) = app.x1x2xnxmmEditField_5.Value;
                     x(6) = app.x1x2xnxmmEditField_6.Value;


                                 f(1) = app.f1f2fnfHzEditField.Value;
                                 f(2) = app.f1f2fnfHzEditField_2.Value;
                                 f(3) = app.f1f2fnfHzEditField_3.Value;
                                 f(4) = app.f1f2fnfHzEditField_4.Value;
                                 f(5) = app.f1f2fnfHzEditField_5.Value;
                                 f(6) = app.f1f2fnfHzEditField_6.Value;


            p=polyfit(x/l,f,2);
            x1 = linspace(min(x/l),max(x/l));
            y1 = polyval(p,x1);
            plot(app.UIAxes,x/l,f,'o',x1,y1);
            legend(app.UIAxes,'原始数据','拟合曲线');
            %顶点的纵坐标（最小f值）
            f_min=min([y1,f]);
            E=1.6067*(l^3)*m/(d^4)*(f_min^2);
            app.EditField.Value = E;
            E_theory=120000000000;
deviation=abs(E-E_theory)/E_theory;
app.EditField_2.Value = 100*deviation
    
if (deviation<=0.05)
    app.TextArea_2.Value = '铜的杨氏模量理论值为0.73~1.27*10^11Pa，室温下铜的杨氏模量约为1.2*10^11Pa。实验的误差在5%%以内，考虑到实验室条件限制等因素，实验结果很接近理论值，可以认为实验是成功的。';
elseif(deviation>0.05&&deviation<=0.1)
    app.TextArea_2.Value =[ '铜的杨氏模量理论值为0.73~1.27*10^11Pa，' ...
        '室温下铜的杨氏模量约为1.2*10^11Pa' ...
        '实验的误差较大，考虑到实验室条件限制等因素该误差仍然在可，' ...
        '接受的范围内，可以认为实验是比较成功的。'];

else
        app.TextArea_2.Value = ['铜的杨氏模量理论值为0.73~1.27*10^11Pa，' ...
        '室温下铜的杨氏模量约为1.2*10^11Pa' ...
        '实验的误差超过10%，请按照实际情况判断实验结果是否符合实' ...
        '际，或者检查实验操作是否正确，仪器运行是否正常等。'];

end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'f-x/l图像')
            xlabel(app.UIAxes, 'x/l')
            ylabel(app.UIAxes, 'f')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '动态法测杨氏模量';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'实验名称：动态法测量杨氏模量。'; ''; '实验目的：'; '1、熟悉动态法测量杨氏模量的基本原理'; '2、掌握动态法测量材料杨氏模量的基本测量方法'; '3、学习用外延法测定式样节点处共振频率'; ''; '实验原理：'; '1、动态杨氏模量：杨氏模量是固体材料在弹性形变范围内正应力与相应正应变的比值，其数值的大小与材料的结构、化学成分和加工制造方法等因素有关。测量杨氏模量的方法可以分为静态法、动态法、波传播法三类。此实验中所采用的动态法既可测量金属的杨氏模量，又可以测量玻璃、陶瓷材料的杨氏模量，测量准确度比较高。'; '2、长度L远远大于直径d的细长棒，只要测出固有频率就可以计算试样的动态杨氏模量，所以整个实验的主要任务就是测量试样的基频振动的固有频率。物体的固有频率和共振频率是两个概念，本实验只能测算出样品的共振频率，但因为两者差距很小，所以可以忽略。 '; ''; '实验内容：'; '1、测量细长棒的长度、直径、质量'; '2、共振频率测量：'; '（1）连接实验装置，并将悬丝分别连接在测试棒0.1L与0.9L处'; '（2）在室温下铜的杨氏模量为1.2x10^10（N*m^-2）,所以先通过测试数据估算出共振频率f，以便寻找共振点，由小到大逐渐调节信号发生器的频率，并观察示波器上信号的变化。当示波器显示的交流信号在某一频率处达到极大，则认为信号发生器的激振频率与测试样共振，几下此时的频率f'; '（3）将悬丝等间隔向中间靠拢，记录f2、f3……'; '（4）用外延法作图，并获取测试棒的固有频率f'; '（5）将f带入公式计算不确定度'; '实验器材及注意事项：'; ''; '注意事项：1、在测量细长棒的属性之后要先估算共振频率f，以便寻找共振点'; '          2、将悬丝等间隔向中间靠近，但要保证d<<L。'; ''; '数据处理与结果：'; '需要输入：长度（mm）、质量（g）、直径（mm）、测量的频率（Hz）f1、f2……以及对'; '应的悬点距离端点距离x（mm）'; '需要计算：各x/L，作图f-x/L，求顶点f。'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '输入完成';
            app.mmEditFieldLabel = uilabel(app.UIFigure);
            app.mmEditFieldLabel.Text = '请输入细棒的长度（mm）';
            app.mmEditField = uieditfield(app.UIFigure, 'numeric');
            app.mmEditField.ValueChangedFcn = createCallbackFcn(app, @mmEditFieldValueChanged, true);
            app.mmEditField.Value = 160;
            app.mmEditField_2Label = uilabel(app.UIFigure);
            app.mmEditField_2Label.Text = '请输入细棒的直径（mm）';
            app.mmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.mmEditField_2.ValueChangedFcn = createCallbackFcn(app, @mmEditField_2ValueChanged, true);
            app.mmEditField_2.Value = 5.979;
            app.gEditFieldLabel = uilabel(app.UIFigure);
            app.gEditFieldLabel.Text = '请输入细棒的质量（g）    ';
            app.gEditField = uieditfield(app.UIFigure, 'numeric');
            app.gEditField.ValueChangedFcn = createCallbackFcn(app, @gEditFieldValueChanged, true);
            app.gEditField.Value = 38;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '实验结果：';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.x1x2xnxmmLabel = uilabel(app.UIFigure);
            app.x1x2xnxmmLabel.Text = '请按 [x1,x2,……,xn] 的格式输入悬点与端点的距离x（单位：mm）';
            app.x1x2xnxmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.x1x2xnxmmEditField.ValueChangedFcn = createCallbackFcn(app, @x1x2xnxmmEditFieldValueChanged, true);
            app.x1x2xnxmmEditField.Value = 10;
            app.f1f2fnfHzLabel = uilabel(app.UIFigure);
            app.f1f2fnfHzLabel.Text = '请按[f1,f2,……,fn]的格式输入共振频率f（单位：Hz）';
            app.f1f2fnfHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.f1f2fnfHzEditField.ValueChangedFcn = createCallbackFcn(app, @f1f2fnfHzEditFieldValueChanged, true);
            app.f1f2fnfHzEditField.Value = 731.45;
            app.x1x2xnxmmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.x1x2xnxmmEditField_2.Value = 20;
            app.x1x2xnxmmEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.x1x2xnxmmEditField_3.Value = 25;
            app.x1x2xnxmmEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.x1x2xnxmmEditField_4.Value = 35;
            app.x1x2xnxmmEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.x1x2xnxmmEditField_5.Value = 45;
            app.x1x2xnxmmEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.x1x2xnxmmEditField_6.Value = 50;
            app.f1f2fnfHzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.f1f2fnfHzEditField_2.Value = 729.18;
            app.f1f2fnfHzEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.f1f2fnfHzEditField_3.Value = 728.4;
            app.f1f2fnfHzEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.f1f2fnfHzEditField_4.Value = 727.85;
            app.f1f2fnfHzEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.f1f2fnfHzEditField_5.Value = 728.16;
            app.f1f2fnfHzEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.f1f2fnfHzEditField_6.Value = 728.66;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '实验评价：';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '误差百分比(%)：';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp1ysml

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end