classdef exp2fgj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label            matlab.ui.control.Label
        TextArea         matlab.ui.control.TextArea
        Label_2          matlab.ui.control.Label
        Label_3          matlab.ui.control.Label
        EditField        matlab.ui.control.NumericEditField
        EditField_2      matlab.ui.control.NumericEditField
        EditField_3      matlab.ui.control.NumericEditField
        Label_4          matlab.ui.control.Label
        Label_5          matlab.ui.control.Label
        Label_6          matlab.ui.control.Label
        EditField_4      matlab.ui.control.NumericEditField
        EditField_5      matlab.ui.control.NumericEditField
        Label_7          matlab.ui.control.Label
        Label_8          matlab.ui.control.Label
        Label_9          matlab.ui.control.Label
        Label_10         matlab.ui.control.Label
        EditField_6      matlab.ui.control.NumericEditField
        EditField_7      matlab.ui.control.NumericEditField
        EditField_8      matlab.ui.control.NumericEditField
        Label_11         matlab.ui.control.Label
        Label_12         matlab.ui.control.Label
        Label_13         matlab.ui.control.Label
        Label_14         matlab.ui.control.Label
        EditField_9      matlab.ui.control.NumericEditField
        EditField_10     matlab.ui.control.NumericEditField
        EditField_11     matlab.ui.control.NumericEditField
        Label_15         matlab.ui.control.Label
        Label_16         matlab.ui.control.Label
        Label_17         matlab.ui.control.Label
        Label_18         matlab.ui.control.Label
        EditField_12     matlab.ui.control.NumericEditField
        Button           matlab.ui.control.Button
        EditField_14     matlab.ui.control.NumericEditField
        EditField_15     matlab.ui.control.NumericEditField
        Label_20         matlab.ui.control.Label
        Label_21         matlab.ui.control.Label
        Label_29         matlab.ui.control.Label
        EditField_16     matlab.ui.control.NumericEditField
        EditField_17     matlab.ui.control.NumericEditField
        EditField_18     matlab.ui.control.NumericEditField
        Label_23         matlab.ui.control.Label
        Label_24         matlab.ui.control.Label
        Label_25         matlab.ui.control.Label
        Label_30         matlab.ui.control.Label
        EditField_19     matlab.ui.control.NumericEditField
        EditField_20     matlab.ui.control.NumericEditField
        EditField_21     matlab.ui.control.NumericEditField
        Label_26         matlab.ui.control.Label
        Label_27         matlab.ui.control.Label
        Label_28         matlab.ui.control.Label
        AEditFieldLabel  matlab.ui.control.Label
        AEditField       matlab.ui.control.NumericEditField
        Label_31         matlab.ui.control.Label
        Label_32         matlab.ui.control.Label
        TextArea_2       matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            x1 = 3600*app.EditField.Value + 60*app.EditField_2.Value+app.EditField_3.Value;
            x2 = 3600*app.EditField_6.Value + 60*app.EditField_4.Value+app.EditField_5.Value;
            y1 = 3600*app.EditField_9.Value + 60*app.EditField_7.Value+app.EditField_8.Value;
            y2 = 3600*app.EditField_12.Value + 60*app.EditField_10.Value+app.EditField_11.Value;
if(x1>y1) 
    z1=x1-y1;
else
    x1=x1+360*3600;
    z1=x1-y1;
end

if(x2>y2)  
    z2=x2-y2;
else
    x2=x2+360*3600;
    z2=x2-y2;
end
a=(z1+z2)/4;
%对z1处理
dmsz1(1)=floor(z1/3600);
dmsz1(2)=floor(mod(z1,3600)/60);
dmsz1(3)=mod(mod(z1,3600),60);
app.EditField_16.Value = dmsz1(1);
app.EditField_14.Value = dmsz1(2);
app.EditField_15.Value = dmsz1(3);
%对z2处理
dmsz2(1)=floor(z2/3600);
dmsz2(2)=floor(mod(z2,3600)/60);
dmsz2(3)=mod(mod(z2,3600),60);
app.EditField_19.Value = dmsz2(1);
app.EditField_17.Value = dmsz2(2);
app.EditField_18.Value = dmsz2(3);
%对a处理
dmsa(1)=floor(a/3600);
dmsa(2)=floor(mod(a,3600)/60);
dmsa(3)=mod(mod(a,3600),60);
app.AEditField.Value = dmsa(1);
app.EditField_20.Value = dmsa(2);
app.EditField_21.Value = dmsa(3);

if((dmsa(1)<=61)&&(dmsa(1)>=59))
    app.TextArea_2.Value='所得的结果误差相对较小，属于比较合理的结果，说明实验是成功的';
elseif((dmsa(1)>61)&&(dmsa(1)<=65))
    app.TextArea_2.Value='所得的结果误差较大但在可以接受的范围内，实验的操作可能仍然存在可以改进的地方';
elseif((dmsa(1)<59)&&(dmsa(1)>=55))
    app.TextArea_2.Value='所得的结果误差较大但在可以接受的范围内，实验的操作可能仍然存在可以改进的地方';
else
    app.TextArea_2.Value='所得的结果误差较大,请检查实验的操作是否正确，仪器是否正常工作，建议重新测量几组数据';
end


        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            value = app.AEditField.Value;
            
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '分光计';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'一、分光计'; '实验名称：分光计'; ''; '指导教师：'; ''; '实验目的：'; '了解分光计的结构，学会正确的分光计调节和使用方法，利用分光计测量三棱镜的顶角。'; ''; '实验原理：'; '1、反射法测量三棱镜的棱角：用平行光入射到三棱镜的棱角，两光线经过三棱镜的反射光线夹角为α。设两读数窗为1、2，当望远镜在右侧时，读数为∠右1、∠右2，同理有∠左1、∠左2，所以α1=∠右1-∠右2，α2=∠左1-∠左2，为了消除仪器偏差，取α=（α1+α2）/2，所以棱角∠A的计算公式为：'; '                   ∠A=（|∠右1-∠左1|+|∠右2-∠左2|）/4'; '2、自准直法：在载物平台上放一镜面垂直于望远镜光轴的平面反射镜，调节亮十字与物镜之间的距离，如果亮十字恰好处于物镜的焦平面上，则亮十字上任意一点发出的光经过物镜变为平行光，此平行光由反射镜反射回来，经物镜后所成的亮十字像应该准确的处在亮十字所处的平面上。所以在调教过程中只要在亮十字所处的平面上看到反射回来的清晰的亮十字像时，望远镜已调焦无穷远了。'; ''; '实验内容：'; '1、分光镜的调整：：保证入射光线是平行光，望远镜能接收平行光，平行光管和望远镜的光轴与分光计中心轴垂直。'; '（1）粗调：目测法调节望远镜倾斜度，调节螺钉使望远镜光轴基本与分光计中心垂直。'; '（2）望远镜调焦无穷远：反射镜置于载物平台，调节目镜使得能够看清叉丝，调节望远镜倾斜螺钉直到找到亮十字像为止，调节调焦螺钉使得出现清晰的亮十字像，使亮十字像与叉丝重合。'; '实验器材及注意事项：'; '实验器材，望远镜、平行光管、载物平台、读数装置'; '注意事项：测量三棱镜顶角时，放置三棱镜顶角于载物台中心偏前一点点处，读数窗读游标刻度盘时亮光线不止一条，读取中间任意一条亮线对应的读数。'; '数据处理与结果：'; '需要测量输入的数据：∠右1，∠右2，∠左1，∠左2'; '需要计算的数据：|∠右1-∠左1|，|∠右2-∠左2|，∠A=（|∠右1-∠左1|+|∠右2-∠左2|）/4    '};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '请输入需要处理的数据';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '∠右1 ';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 74;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 9;
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '。';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '''';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '"';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Value = 10;
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '。';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '''';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '"';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '∠右2 ';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Value = 254;
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Value = 15;
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '。';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '''';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '"';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '∠左1';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.Value = 314;
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10.Value = 14;
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '。';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '''';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '"';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '∠左2 ';
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_12.Value = 134;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始计算';
            app.EditField_14 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_15 = uieditfield(app.UIFigure, 'numeric');
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '。';
            app.Label_21 = uilabel(app.UIFigure);
            app.Label_21.Text = '''';
            app.Label_29 = uilabel(app.UIFigure);
            app.Label_29.Text = '中间量|∠右1-∠左1|';
            app.EditField_16 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_17 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_18 = uieditfield(app.UIFigure, 'numeric');
            app.Label_23 = uilabel(app.UIFigure);
            app.Label_23.Text = '。';
            app.Label_24 = uilabel(app.UIFigure);
            app.Label_24.Text = '''';
            app.Label_25 = uilabel(app.UIFigure);
            app.Label_25.Text = '"';
            app.Label_30 = uilabel(app.UIFigure);
            app.Label_30.Text = '中间量|∠右2-∠左2';
            app.EditField_19 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_20 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_21 = uieditfield(app.UIFigure, 'numeric');
            app.Label_26 = uilabel(app.UIFigure);
            app.Label_26.Text = '。';
            app.Label_27 = uilabel(app.UIFigure);
            app.Label_27.Text = '''';
            app.Label_28 = uilabel(app.UIFigure);
            app.Label_28.Text = '"';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = '三棱镜的顶角∠A';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.Label_31 = uilabel(app.UIFigure);
            app.Label_31.Text = '"';
            app.Label_32 = uilabel(app.UIFigure);
            app.Label_32.Text = '实验数据的评估';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp2fgj

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end