classdef exp3wyb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label_2           matlab.ui.control.Label
        Button            matlab.ui.control.Button
        IAEditField_7     matlab.ui.control.NumericEditField
        IAEditField_6     matlab.ui.control.NumericEditField
        IAEditField_5     matlab.ui.control.NumericEditField
        IAEditField_4     matlab.ui.control.NumericEditField
        IAEditField_3     matlab.ui.control.NumericEditField
        IAEditField_2     matlab.ui.control.NumericEditField
        IAEditField_1     matlab.ui.control.NumericEditField
        IAEditFieldLabel  matlab.ui.control.Label
        GAEditField_7     matlab.ui.control.NumericEditField
        GAEditField_6     matlab.ui.control.NumericEditField
        GAEditField_5     matlab.ui.control.NumericEditField
        GAEditField_4     matlab.ui.control.NumericEditField
        GAEditField_3     matlab.ui.control.NumericEditField
        GAEditField_2     matlab.ui.control.NumericEditField
        GAEditField_1     matlab.ui.control.NumericEditField
        GAEditFieldLabel  matlab.ui.control.Label
        kEditField        matlab.ui.control.NumericEditField
        kEditFieldLabel   matlab.ui.control.Label
        TextArea          matlab.ui.control.TextArea
        Label             matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
                                     G(1) = app.GAEditField_1.Value;
                                     G(2) = app.GAEditField_2.Value;
                                     G(3) = app.GAEditField_3.Value;
                                     G(4) = app.GAEditField_4.Value;
                                     G(5) = app.GAEditField_5.Value;
                                     G(6) = app.GAEditField_6.Value;
                                     G(7) = app.GAEditField_7.Value;
            
                                     I(1) = app.IAEditField_1.Value;
                                     I(2) = app.IAEditField_2.Value;
                                     I(3) = app.IAEditField_3.Value;
                                     I(4) = app.IAEditField_4.Value;
                                     I(5) = app.IAEditField_5.Value;
                                     I(6) = app.IAEditField_6.Value;
                                     I(7) = app.IAEditField_7.Value;
           
                                     deltaI = I-G;
           plot(app.UIAxes,G,deltaI,'-',G,[0,0,0,0,0,0,0]);
            title(app.UIAxes,'图1 δI-I校准图');
            xlabel(app.UIAxes,'I/A');
ylabel(app.UIAxes,'δI/A');
app.kEditField.Value = 100*max(deltaI)/max(I);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '简易万用表的设计';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'浙江大学物理实验报告内容'; ''; '实验名称：简易万用表设计'; ''; '指导教师：'; ''; '实验目的：'; '1.了解万用表测量电压、电流以及电阻的基本原理'; '2.掌握多量程万用表的制作方法'; ''; '实验原理：'; '万用表主要由磁电式电流计以及一系列电阻构成。由磁电式电流计和不同阻值的分流电阻可构成不同量程的电流表，同样，磁电式电流计和不同阻值分压电阻就构成了不同量程的电压表。'; '    电流计允许通过的最大电流称为电流计的量程，用Ig表示，电流计线圈有一定的电阻称为电流计的内阻，用Rg 表示。量程Ig 与内阻 Rg 是电流计特性的两个重要参数。 '; '1、电流表的扩程'; '要将磁电式电表改装成量程为I 的电流表，只需在电表表头两端并联一个分流电阻，如图1-1分流电阻阻值按以下公式计算 ：如果I =  n *Ig '; '并联不同的分流电阻可构成不同量程的电流表。如图1-2所示电流表有四个不同量程。 '; ' '; '2、电压表的改装'; '	    如果要将表头改装成量程为U的电压表，则表头需串联一分压电阻，分压电阻阻值按如下公式计算：串联不同的分压电阻，得到不同量程的电压表如图2-2'; ''; ''; ''; ''; ''; '实验内容：'; '1.设计多量程电流表并校准'; '将标准表与被测表按图连接起调节可变电阻，直到被测表G满偏，这时标准表的指示值就是Ig'; '将转换开关由A转向B，并保持变阻器位置不变，调节变阻器使标准表又出现原先的指示值，那么标准表变阻器的指示值就是Rg'; ''; '根据理论推导电流的量程与它分流电阻的乘积是一个常数，即'; '即可得到以下公式'; ''; '校准与定级'; '1.接线图如下'; ''; '2.记录数据表'; ''; '3.校准曲线'; ''; '4.定级'; ''; ''; '2.设计多量程伏特表'; ''; '校准与定级'; '1.接线如下图'; ''; '2.其他步骤同电流表类同'; '3.设计欧姆表'; '如果要将表头改成欧姆表，可由下图说明，开始短接a、b两端，调节电阻Rˊ使得电流计满刻度，此时：当Rx接入回路后，回路电流为：'; '当时，Ix = I0 / 2，此时电流表指针指向刻度线中点，这时的电阻Rx称为欧姆表的中值电阻。 '; ''; '4.简易万用表实验总体线路图'; ''; '实验器材及注意事项：'; ''; ''; '数据处理与结果：'; '1.记录改装电流表与标准电流表数据，并作图'; '2.记录改装电压表与标准电流表数据，并做图'; '3.记录改装欧姆表与标准电阻值数据，并做图'};
            app.kEditFieldLabel = uilabel(app.UIFigure);
            app.kEditFieldLabel.Text = '定级k';
            app.kEditField = uieditfield(app.UIFigure, 'numeric');
            app.kEditField.Value = 3.33;
            app.GAEditFieldLabel = uilabel(app.UIFigure);
            app.GAEditFieldLabel.Text = 'G/A';
            app.GAEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.GAEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.GAEditField_2.Value = 1;
            app.GAEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.GAEditField_3.Value = 2;
            app.GAEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.GAEditField_4.Value = 3;
            app.GAEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.GAEditField_5.Value = 4;
            app.GAEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.GAEditField_6.Value = 2.5;
            app.GAEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.IAEditFieldLabel = uilabel(app.UIFigure);
            app.IAEditFieldLabel.Text = 'I/A';
            app.IAEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.IAEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.IAEditField_2.Value = 0.2;
            app.IAEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.IAEditField_3.Value = 0.42;
            app.IAEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.IAEditField_4.Value = 0.6;
            app.IAEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.IAEditField_5.Value = 0.8;
            app.IAEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.IAEditField_6.Value = 0.98;
            app.IAEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '输入完成';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp3wyb

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end