classdef exp4mlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Label                 matlab.ui.control.Label
        TextArea              matlab.ui.control.TextArea
        Label_2               matlab.ui.control.Label
        TextArea_2            matlab.ui.control.TextArea
        UITable               matlab.ui.control.Table
        mmLabel               matlab.ui.control.Label
        UEditFieldLabel       matlab.ui.control.Label
        EditField2_1          matlab.ui.control.NumericEditField
        EditField2_2          matlab.ui.control.NumericEditField
        EditField2_3          matlab.ui.control.NumericEditField
        EditField2_4          matlab.ui.control.NumericEditField
        EditField2_5          matlab.ui.control.NumericEditField
        EditField2_6          matlab.ui.control.NumericEditField
        EditField2_7          matlab.ui.control.NumericEditField
        EditField2_8          matlab.ui.control.NumericEditField
        Label_4               matlab.ui.control.Label
        EditField_9           matlab.ui.control.NumericEditField
        EditField2_9          matlab.ui.control.NumericEditField
        EditField2_10         matlab.ui.control.NumericEditField
        EditField2_11         matlab.ui.control.NumericEditField
        EditField2_12         matlab.ui.control.NumericEditField
        EditField2_13         matlab.ui.control.NumericEditField
        EditField2_14         matlab.ui.control.NumericEditField
        EditField2_15         matlab.ui.control.NumericEditField
        UEditField_3          matlab.ui.control.NumericEditField
        EditField2_16         matlab.ui.control.NumericEditField
        EditField2_17         matlab.ui.control.NumericEditField
        EditField2_18         matlab.ui.control.NumericEditField
        EditField2_19         matlab.ui.control.NumericEditField
        EditField2_20         matlab.ui.control.NumericEditField
        EditField2_21         matlab.ui.control.NumericEditField
        EditField2_22         matlab.ui.control.NumericEditField
        sLabel                matlab.ui.control.Label
        Button_2              matlab.ui.control.Button
        Button                matlab.ui.control.Button
        Label_3               matlab.ui.control.Label
        e1019CEditFieldLabel  matlab.ui.control.Label
        e1019CEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.EditField2_9.Value = app.EditField_9.Value;
                                    app.EditField2_10.Value = app.EditField_9.Value;
                                    app.EditField2_11.Value = app.EditField_9.Value;
                                    app.EditField2_12.Value = app.EditField_9.Value;
                                    app.EditField2_13.Value = app.EditField_9.Value;
                                    app.EditField2_14.Value = app.EditField_9.Value;
                                    app.EditField2_15.Value = app.EditField_9.Value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            U(1) = app.EditField2_1.Value;
                              U(2) = app.EditField2_2.Value;
                              U(3) = app.EditField2_3.Value;
                              U(4) = app.EditField2_4.Value;
                              U(5) = app.EditField2_5.Value;
                              U(6) = app.EditField2_6.Value;
                              U(7) = app.EditField2_7.Value;
                              U(8) = app.EditField2_8.Value;
            L(1) = app.EditField_9.Value;
                              L(2) = app.EditField2_9.Value;
                              L(3) = app.EditField2_10.Value;
                              L(4) = app.EditField2_11.Value;
                              L(5) = app.EditField2_12.Value;
                              L(6) = app.EditField2_13.Value;
                              L(7) = app.EditField2_14.Value;
                              L(8) = app.EditField2_15.Value;
            t(1) = app.UEditField_3.Value;
                              t(2) = app.EditField2_16.Value;
                              t(3) = app.EditField2_17.Value;
                              t(4) = app.EditField2_18.Value;
                              t(5) = app.EditField2_19.Value;
                              t(6) = app.EditField2_20.Value;
                              t(7) = app.EditField2_21.Value;
                              t(8) = app.EditField2_22.Value;L=L/1000;

midu=981;
g=9.8;
nianzhi=0.0000183;
d=0.005;
b=6.17*10^(-6);
p=76;

n=length(U);
i=1;
while(i<=n)
a=sqrt((9*nianzhi*L(i))/(t(i)*2*midu*g));
q(i)=(18*pi/sqrt(2*midu*g))*((nianzhi*L(i))/(t(i)*(1+b/(p*a))))^(3/2)*d/U(i);
i=i+1;
end
q=q*10^(19);
j=1;
while(j<=n)
    if (round(q(j)/1.6)<1)
       e(j)=q(j);
    else
       e(j)=q(j)/(round(q(j)/1.6));
    end
    j=j+1;
end
x=48849/30280;
app.e1019CEditField.Value = mean(e);

result1(1,1:8) = 1:8;
result1(2,1:8) = U;
result1(3,1:8) = L;
result1(4,1:8) = t;
result1(5,1:8) = q;
result1(6,1:8) = e;
average_e = mean(e);
            
        set(app.UITable,'RowName',{'实验次数','U/V','l/mm','t/s','qi/10^-19C','e/10^-19C'});
        app.UITable.Data = result1;
        %评价
        if(average_e>1*10^(-19)&&average_e<5*10^(-19))
app.TextArea_2.Value = ['现已知元电荷的电荷量约为1.6*10^(-19)C，据此对实验数据进行评价。由于实验室条件、实验设计等因素，本实验可能会产生较大误差，因此对实验数据的评价较为宽松。实验数据较为接近实际，可以认为实验很成功'];
elseif(average_e>10^(-20)&&average_e<10^(-18))
    app.TextArea_2.Value = ['现已知元电荷的电荷量约为1.6*10^(-19)C，据此对实验数据进行评价。由于实验室条件、实验设计等因素，本实验可能会产生较大误差，因此对实验数据的评价较为宽松。实验数据数量级与实际接近，可以通过实验基本确定了元电荷的数量级，基本可以认为实验成功'];
        else
            app.TextArea_2.Value = ['现已知元电荷的电荷量约为1.6*10^(-19)C，据此对实验数据进行评价。由于实验室条件、实验设计等因素，本实验可能会产生较大误差，因此对实验数据的评价较为宽松。实验数据与实际差距较大，不排除仪器参数差异和环境因素产生的影响可以尝试手动对实验数据进行处理，或者重新进行实验\n'];
end
        

        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '密立根油滴实验';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'浙江大学物理实验报告内容'; ''; '实验名称：'; '密立根油滴实验'; '指导教师：'; ''; '实验目的：'; '1.测定油滴的带电量，并推算出电子电荷量e的大小'; '2.验证电荷的不连续性'; '实验原理：'; '1.油滴在静电场中匀速运动时'; ''; ''; '2.油滴在无电场的重力场中考虑粘滞阻力匀速运动时'; ''; '当油滴半径小于10^-6m，粘滞阻力应作出修正'; ''; '修正后油滴半径为'; ''; '右式中的半径可由修正前的公式代入计算'; ''; '3.油滴的质量为'; ''; ''; ''; ''; '综上可得'; ''; '因此只要测得油滴的平衡电压V及不带电场时，油滴匀速下落的速度，就可测出油滴的带电量'; '由上述结果，我们可以发现，它们都满足以下方程'; ''; '其中n时不同的整数，而e是不变的值，由此可得出电荷的不连续性'; '实验内容：'; '1.联系掌握正确的测量技术'; '包括对密立根仪的平衡调节，选择适当的电压，适当大小的油滴和准确控制计时等。'; '从喷雾口将油滴喷入，调节CCD聚集，就能清晰地显示油滴的图形'; '2.多次测量同一油滴在一定电压下匀速降落的距离，及其所用的时间'; '对不同的多颗油滴再进行相同的测量。验证不同油滴所带电量都是某一公约数（电子电荷）的倍数。'; ''; '实验器材及注意事项：'; ''; '数据处理与结果：'; '将常数代入上述公式可得最终公式'; ''; '将测得数据代入公式求得电荷量'; '用“逐次相减法”对实验测量的各实验数据求最大公约数'; '或用“作图法”，将数据作图，直线的近似斜率即为e'};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '数据评价';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.mmLabel = uilabel(app.UIFigure);
            app.mmLabel.Text = '下落距离/mm';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '归一';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '若距离相同，输入第一个，点击';
            app.UEditFieldLabel = uilabel(app.UIFigure);
            app.UEditFieldLabel.Text = '电压U';
            app.EditField2_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_1.Value = 29;
            app.EditField2_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_2.Value = 96;
            app.EditField2_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_3.Value = 21;
            app.EditField2_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_4.Value = 14;
            app.EditField2_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_5.Value = 22;
            app.EditField2_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_6.Value = 42;
            app.EditField2_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_7.Value = 69;
            app.EditField2_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_8.Value = 12;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '输入';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.Value = 5;
            app.EditField2_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_12 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_13 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_14 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_15 = uieditfield(app.UIFigure, 'numeric');
            app.UEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.UEditField_3.Value = 83.52;
            app.EditField2_16 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_16.Value = 39.93;
            app.EditField2_17 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_17.Value = 66.56;
            app.EditField2_18 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_18.Value = 68.77;
            app.EditField2_19 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_19.Value = 43.19;
            app.EditField2_20 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_20.Value = 21.95;
            app.EditField2_21 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_21.Value = 14.73;
            app.EditField2_22 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_22.Value = 41.02;
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = '下落时间/s';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '完成输入';
            app.e1019CEditFieldLabel = uilabel(app.UIFigure);
            app.e1019CEditFieldLabel.Text = 'e/10^-19C';
            app.e1019CEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp4mlg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end