classdef exp4sscd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label             matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        EditField_3       matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        TextArea          matlab.ui.control.TextArea
        EditField_4       matlab.ui.control.NumericEditField
        EditField_5       matlab.ui.control.NumericEditField
        EditField_6       matlab.ui.control.NumericEditField
        EditField_7       matlab.ui.control.NumericEditField
        EditField_8       matlab.ui.control.NumericEditField
        EditField_9       matlab.ui.control.NumericEditField
        EditField_10      matlab.ui.control.NumericEditField
        Button            matlab.ui.control.Button
        Label_4           matlab.ui.control.Label
        EditField_11      matlab.ui.control.NumericEditField
        vEditFieldLabel   matlab.ui.control.Label
        vEditField        matlab.ui.control.NumericEditField
        mmLabel           matlab.ui.control.Label
        msLabel           matlab.ui.control.Label
        Label_7           matlab.ui.control.Label
        TextArea_2        matlab.ui.control.TextArea
        Label_9           matlab.ui.control.Label
        EditField_12      matlab.ui.control.NumericEditField
        Label_8           matlab.ui.control.Label
        vLabel            matlab.ui.control.Label
        vEditField_2      matlab.ui.control.NumericEditField
        msLabel_2         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            T = app.EditField.Value;
            f = app.EditField_2.Value;
            distance(1) = app.EditField_3.Value;
            distance(2) = app.EditField_4.Value;
            distance(3) = app.EditField_5.Value;
            distance(4) = app.EditField_6.Value;
            distance(5) = app.EditField_7.Value;
            distance(6) = app.EditField_8.Value;
            distance(7) = app.EditField_9.Value;
            distance(8) = app.EditField_10.Value;
digits(8);
v_theory =331.45*sqrt(1+T/273.15);

l_distance=length(distance);
i=1;
while (i<l_distance)
    delta_distance(i)=distance(i+1)-distance(i);
    i=i+1;
end

wavelength=mean(delta_distance)*2;
v=f*wavelength;

app.EditField_11.Value = wavelength;
app.vEditField.Value   = v;


if v>v_theory
    delta_v = v-v_theory;
else
    delta_v = v_theory-v;
end
app.vEditField_2.Value = v_theory;
E = delta_v/v_theory;
app.EditField_12.Value = E*100;
if (E<=0.025)
    app.TextArea_2.Value='实验测得的声速数值与该温度下理论值很接近，可以认为实验很成功';
elseif((E>0.025)&&(E<0.05))
    app.TextArea_2.Value=['实验的误差较大，但仍然在可以接受的范围内，可以基本认为实验成功' ...
        '可以尝试通过检查实验操作，增加测试次数等手段进一步减小误差\n'];
else
    app.TextArea_2.Value =['实验的误差很大，请思考以下问题：' ...
        '1、输入的温度是否为实验环境的温度' ...
        '2、输入的频率是否正确' ...
        '3、是否按照上述要求的单位进行输入' ...
        '4、实验操作是否完全符合要求'];
end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            
        end

        % Value changed function: vEditField
        function vEditFieldValueChanged(app, event)
            value = app.vEditField.Value;
            
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            value = app.EditField_12.Value;
            
        end

        % Value changed function: vEditField_2
        function vEditField_2ValueChanged(app, event)
            value = app.vEditField_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '谐振频率';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 13.8;
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '环境温度';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 39.54;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '接收端位置读数(请按顺序输入接收端读数)';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Value = 6.575;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '声速测定';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'浙江大学物理实验报告内容'; ''; '实验名称：声速的测量'; '   '; '实验目的：'; '了解声波的特性，加深对振动合成和波动干涉理论的理解。'; '学会使用相位差法和驻波法测量声波在空气中传播的速度。'; '学习示波器和信号发生器的使用。'; ''; '实验原理：'; '1、超声波的传播速度：声波在理想气体中的传播可以认为是绝热传播，其传播速度为v=(γRT/M)^(1/2)（其中M为气体摩尔质量，R为摩尔气体常量（R=8.314J/(mol*k)），γ为气体比热容比，T为气体热力学温度）。在0℃时，声速为v0=331.45m/s，因此温度t时，声速为vt=331.45*(1+t/273.15)^(1/2) m/s。'; '声波在不同介质中v不同，最简单的方法是直接测定声波振动的频率f和波长λ，得：v=fλ。由于声波的频率一般是仪器给定，故测得声波波长就能得出声速，常用测波长的方法为驻波法和相位比较法。'; '2、驻波法测定超声波波长：由于入射声波和反射声波想干叠加，两换能器之间形成共振驻波现象， 即波幅达极大，由纵波性质可证明，振动位移处以波节时则声压处于波腹，即接受器端通过振动位移为一波节时，接收到的声压最大电信号也最强。'; '驻波共振条件为发射面到接受面之间，距离L恰好为半波长的整数倍，即：Ln=n*λ/2 （n=1，2……）时，接收端每移动距离L，使示波器上再次观察到最大振幅，其移动距离：△L=Ln+1 – Ln= λ/2 。'; '3、相位比较法测定超声波波长：波是振动状态的传播，也可以说是相位的传播。沿波的传播方向上的任意两点，其振动状态相同，或者说相位差为2Π的整数倍时，两点间的距离应该等于波长λ的整数倍。'; ''; '实验内容：'; '1、系统调节：将移动端换能器尽量靠近固定端换能器（几个毫米），并调节两个端呈平行，将接收端信号输入示波器Y轴，在信号发生器上，调节频率旋钮，选择谐振频率约为40kHz，即发射端换能器的固有频率。有时往往发射端和接收端的换能器固有频率并不完全相同，因而在示波器上看到的不一定是最大振幅的正弦波，此时微调信号发生器的频率旋钮，直到示波器上出现振幅最大为止。这时，显示的频率数值才是实验时所需的谐振频率。'; '2、驻波法测量声速：调节好超声换能器至最佳工作状态后，可将移动端接收端在标尺上来回移动，观察干涉现象。缓慢移动接收端，使示波器上出现最大振幅波形，从标尺上读得此时的位置读数L1，继续同一方向移动接收端，逐次读记相邻最大振幅得位置Li，连续记录八个数据，同时记下频率f，若显示频率有微小增减，可读记其实频率f1和结束测量的频率f2，计算声速时用f=1/2*（f1+f2）。'; '3、相位差发测量声速：将发射端得信号输入示波器x轴，移动接收端可以在示波器上看到一、三象限直线，从标尺上读得此时的位置读数L1。继续移动接收端，测定示波器上看到二、四象限直线，标尺上读得此时L2，同时记录下此时f连续记录8个数据。'; ''; '实验器材及注意事项：'; '    实验器材：声速测定仪、压电陶瓷头'; '注意事项：1、使信号频率与两个具有相同固有频率的换能器的频率一致，同时还要使'; '发射端面和接收端面相平行。'; '              2、若驻波法测声速时显示频率有微增或减，可以读记起始f1与结束f2进'; '行计算'; '数据处理与结果：'; '输入：驻波法接收端位置读数L1~L8（mm）；相位差法接收端位置读数L1~L8，测量频'; '率f'; '计算：λ/2=Ln+1 – Ln，λ/2平均数，v=λf'};
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Value = 10.25;
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Value = 14.722;
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Value = 19.22;
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Value = 23.538;
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.Value = 28.151;
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.Value = 32.875;
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10.Value = 37.195;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始计算';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '根据输入数据计算得 λ=';
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.vEditFieldLabel = uilabel(app.UIFigure);
            app.vEditFieldLabel.Text = ' v=';
            app.vEditField = uieditfield(app.UIFigure, 'numeric');
            app.vEditField.ValueChangedFcn = createCallbackFcn(app, @vEditFieldValueChanged, true);
            app.mmLabel = uilabel(app.UIFigure);
            app.mmLabel.Text = 'mm';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'm/s';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '数据评价';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '误差';
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '%';
            app.vLabel = uilabel(app.UIFigure);
            app.vLabel.Text = '此温度下真实v';
            app.vEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.vEditField_2.ValueChangedFcn = createCallbackFcn(app, @vEditField_2ValueChanged, true);
            app.msLabel_2 = uilabel(app.UIFigure);
            app.msLabel_2.Text = 'm/s';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp4sscd

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end