classdef exp6xzyt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Button_3              matlab.ui.control.Button
        EditField_2           matlab.ui.control.NumericEditField
        EditField_1           matlab.ui.control.NumericEditField
        Label_6               matlab.ui.control.Label
        Label_5               matlab.ui.control.Label
        H2cmEditFieldLabel_3  matlab.ui.control.Label
        H2cmEditFieldLabel_2  matlab.ui.control.Label
        n1EditFieldLabel_4    matlab.ui.control.Label
        DEditField_36         matlab.ui.control.NumericEditField
        DEditField_35         matlab.ui.control.NumericEditField
        DEditField_34         matlab.ui.control.NumericEditField
        DEditField_33         matlab.ui.control.NumericEditField
        DEditField_32         matlab.ui.control.NumericEditField
        DEditField_31         matlab.ui.control.NumericEditField
        DEditField_30         matlab.ui.control.NumericEditField
        DEditField_29         matlab.ui.control.NumericEditField
        DEditField_28         matlab.ui.control.NumericEditField
        DEditField_27         matlab.ui.control.NumericEditField
        DEditField_26         matlab.ui.control.NumericEditField
        DEditField_25         matlab.ui.control.NumericEditField
        DEditField_24         matlab.ui.control.NumericEditField
        DEditField_23         matlab.ui.control.NumericEditField
        DEditField_22         matlab.ui.control.NumericEditField
        DEditField_21         matlab.ui.control.NumericEditField
        DEditField_20         matlab.ui.control.NumericEditField
        DEditField_19         matlab.ui.control.NumericEditField
        g2cms2EditField       matlab.ui.control.NumericEditField
        g2cms2EditFieldLabel  matlab.ui.control.Label
        g1cms2EditField       matlab.ui.control.NumericEditField
        g1cms2EditFieldLabel  matlab.ui.control.Label
        Method2Label          matlab.ui.control.Label
        Button_2              matlab.ui.control.Button
        n1EditFieldLabel_3    matlab.ui.control.Label
        DEditField_18         matlab.ui.control.NumericEditField
        DEditField_17         matlab.ui.control.NumericEditField
        DEditField_16         matlab.ui.control.NumericEditField
        DEditField_15         matlab.ui.control.NumericEditField
        DEditField_14         matlab.ui.control.NumericEditField
        DEditField_13         matlab.ui.control.NumericEditField
        n1EditFieldLabel_2    matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        DEditField_12         matlab.ui.control.NumericEditField
        DEditField_11         matlab.ui.control.NumericEditField
        DEditField_10         matlab.ui.control.NumericEditField
        DEditField_9          matlab.ui.control.NumericEditField
        DEditField_8          matlab.ui.control.NumericEditField
        DEditField_7          matlab.ui.control.NumericEditField
        h2cmEditField         matlab.ui.control.NumericEditField
        h2cmEditFieldLabel    matlab.ui.control.Label
        H2cmEditField         matlab.ui.control.NumericEditField
        H2cmEditFieldLabel    matlab.ui.control.Label
        UITable               matlab.ui.control.Table
        Method3Label          matlab.ui.control.Label
        Method1Label          matlab.ui.control.Label
        h1cmEditField_6       matlab.ui.control.NumericEditField
        h1cmEditField_5       matlab.ui.control.NumericEditField
        h1cmEditField_4       matlab.ui.control.NumericEditField
        h1cmEditField_3       matlab.ui.control.NumericEditField
        h1cmEditField_2       matlab.ui.control.NumericEditField
        h1cmEditField_1       matlab.ui.control.NumericEditField
        h1cmEditFieldLabel    matlab.ui.control.Label
        n1EditField_6         matlab.ui.control.NumericEditField
        n1EditField_5         matlab.ui.control.NumericEditField
        n1EditField_4         matlab.ui.control.NumericEditField
        n1EditField_3         matlab.ui.control.NumericEditField
        n1EditField_2         matlab.ui.control.NumericEditField
        n1EditField_1         matlab.ui.control.NumericEditField
        n1EditFieldLabel      matlab.ui.control.Label
        DEditField_6          matlab.ui.control.NumericEditField
        DEditField_5          matlab.ui.control.NumericEditField
        DEditField_4          matlab.ui.control.NumericEditField
        DEditField_3          matlab.ui.control.NumericEditField
        DEditField_2          matlab.ui.control.NumericEditField
        DEditField_1          matlab.ui.control.NumericEditField
        DcmEditFieldLabel     matlab.ui.control.Label
        Button                matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
        Label                 matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
D(1) = app.DEditField_1.Value;
D(2) = app.DEditField_2.Value;
D(3) = app.DEditField_3.Value;
D(4) = app.DEditField_4.Value;
D(5) = app.DEditField_5.Value;
D(6) = app.DEditField_6.Value;
n1(1) = app.n1EditField_1.Value;
n1(2) = app.n1EditField_2.Value;
n1(3) = app.n1EditField_3.Value;
n1(4) = app.n1EditField_4.Value;
n1(5) = app.n1EditField_5.Value;
n1(6) = app.n1EditField_6.Value;
delta_h1(1) = app.h1cmEditField_1.Value; 
delta_h1(2) = app.h1cmEditField_2.Value;
delta_h1(3) = app.h1cmEditField_3.Value;
delta_h1(4) = app.h1cmEditField_4.Value;
delta_h1(5) = app.h1cmEditField_5.Value;
delta_h1(6) = app.h1cmEditField_6.Value;

average_D = mean(D);
g1 = pi^2.*average_D^2.*n1.^2./7200./delta_h1;
average_g1 = mean(g1);
result1=zeros(4,6);
result1(1,1:6) = 1:6;
result1(2,1:6) = n1;
result1(3,1:6) = delta_h1;
result1(4,1:6) = g1;
delta_g1 = std(g1)/sqrt(5);

            
        set(app.UITable,'RowName',{'实验次数','转速n(转/min)','高度差δh/cm','g/(cm/s2)'});
        app.UITable.Data = result1;
        app.g1cms2EditField.Value = average_g1;
        app.EditField_1.Value     = delta_g1
        end

        % Value changed function: h1cmEditField_4
        function h1cmEditField_4ValueChanged(app, event)
            value = app.h1cmEditField_4.Value;
           
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
D(1) = app.DEditField_1.Value;
D(2) = app.DEditField_2.Value;
D(3) = app.DEditField_3.Value;
D(4) = app.DEditField_4.Value;
D(5) = app.DEditField_5.Value;
D(6) = app.DEditField_6.Value;
average_D = mean(D);

            H2 = app.H2cmEditField.Value;
            h2 = app.h2cmEditField.Value;
            
            n2(1) = app.DEditField_7.Value;
            n2(2) = app.DEditField_8.Value;
            n2(3) = app.DEditField_9.Value;
            n2(4) = app.DEditField_10.Value;
            n2(5) = app.DEditField_11.Value;
            n2(6) = app.DEditField_12.Value;
            
            d2(1) = app.DEditField_13.Value;
            d2(2) = app.DEditField_14.Value;
            d2(3) = app.DEditField_15.Value;
            d2(4) = app.DEditField_16.Value;
            d2(5) = app.DEditField_17.Value;
            d2(6) = app.DEditField_18.Value;
tan2xita = d2./(10*(H2-h2));
xita = atan(tan2xita)/2;
tan1xita = tan(xita);
xita = 180.*xita/pi;
g2 = 2*pi^2.*average_D.*n2.^2./3600/sqrt(2)./tan1xita;
average_g2 = mean(g2);
result2 = zeros(7,6);
result2(1,1:6) = 1:6;
result2(2,1:6) = n2;
result2(3,1:6) = d2;
result2(4,1:6) = tan2xita;
result2(5,1:6) = xita;
result2(6,1:6) = tan1xita;
result2(7,1:6) = g2;
delta_g2 = std(g2)/sqrt(5);

        set(app.UITable,'RowName',{'实验次数','转速n(转/min)','BC间距d/mm','tan2θ=d/(H-h)','θ','tanθ','g/(cm/s2)'});
        app.UITable.Data = result2;
                app.g2cms2EditField.Value = average_g2;
                 app.EditField_2.Value    =  delta_g2;
                 
%画图
x = n2.^2;
y = tan1xita;

p = polyfit(x,y,1);
x1 = 0:100:15000;
y1 = p(1).*x1+p(2);
plot(app.UIAxes,x,y,'o',x1,y1);
legend(app.UIAxes,'原始数据','拟合曲线');
title(app.UIAxes,'图1 tanθ-n^2直线拟合图')
xlabel(app.UIAxes,'n^2/(r/min)^2')
ylabel(app.UIAxes,'tanθ')
        end

        % Value changed function: H2cmEditField
        function H2cmEditFieldValueChanged(app, event)
            value = app.H2cmEditField.Value;
            
        end

        % Value changed function: g1cms2EditField
        function g1cms2EditFieldValueChanged(app, event)
            value = app.g1cms2EditField.Value;
            
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(app, event)
            value = app.EditField_1.Value;
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
                        n3(1) = app.DEditField_19.Value;
                        n3(2) = app.DEditField_20.Value;
                        n3(3) = app.DEditField_21.Value;
                        n3(4) = app.DEditField_22.Value;
                        n3(5) = app.DEditField_23.Value;
                        n3(6) = app.DEditField_24.Value;
                        
                        H3(1) = app.DEditField_25.Value;
                        H3(2) = app.DEditField_26.Value;
                        H3(3) = app.DEditField_27.Value;
                        H3(4) = app.DEditField_28.Value;
                        H3(5) = app.DEditField_29.Value;
                        H3(6) = app.DEditField_30.Value;
                        
                        h3(1) = app.DEditField_31.Value;
                        h3(2) = app.DEditField_32.Value;
                        h3(3) = app.DEditField_33.Value;
                        h3(4) = app.DEditField_34.Value;
                        h3(5) = app.DEditField_35.Value;
                        h3(6) = app.DEditField_36.Value;
                        g = 979.30;
                        
                        f3 = H3-h3;
g3 = g.*3600./ (8.*pi.^2.*n3.^2);

result3 = zeros(4,6);
result3(1,1:6) = 1:6;
result3(2,1:6) = n3;
result3(3,1:6) = f3;
result3(4,1:6) = g3;
x = sort(n3);
x(1:6) = x(6:-1:1);
x1 = x;

y = sort(f3);
y1 = sort(g3);
plot(app.UIAxes,x,y,'-',x1,y1);
legend(app.UIAxes,'测得焦距','理论焦距');
title(app.UIAxes,'图2 转速-焦距图')
xlabel(app.UIAxes,'n/(r/min)')
ylabel(app.UIAxes,'f/cm')
set(app.UITable,'RowName',{'实验次数','转速n(转/min)','测得焦距d/cm','计算得焦距/cm'});
        app.UITable.Data = result3;
function s1 = sort(a) 
   l = length(a);
   s1(1:l) = a(1:l);
   i = 0;
   j = 0;
   for (i = 2:l)
      for (j = l:-1:i)
          if s1(j) >= s1(j-1)
              temp = s1(j);
              s1(j) = s1(j-1);
              s1(j-1) = temp; 
          end
      end
   end
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '旋转液体综合实验';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'浙江大学物理实验报告内容'; ''; '实验名称：旋转液体综合实验'; ''; '实验目的: '; '1.了解旋转的液体抛物面成因及原理'; '2.学会旋转液体法测量重力加速度'; ''; '实验内容：'; '◆  仪器调整'; 'a、水平调整   '; '将圆形水平仪放在载物台中心，调整仪器底部支撑脚，直到水平仪上的气泡到中心位置。'; 'b、激光器位置调整'; '    用自准直法调整激光头，使激光束平行转轴入射，经过透明屏幕，对准在桶底处                 的 蓝点记号上。经液面反射的光束与入射光在同一条线上，即在屏幕上的两光点要调重合。'; '◆ 测量重力加速度g'; 'a、用旋转液体液面最高与最低处的高度差测量重力加速度'; '   改变圆桶转速6次，测量液面最高与最低处的高度差，计算重力加速度。'; 'b、斜率法测重力加速度'; '将透明屏幕（1）置于圆桶上方，用自准直法调整激光束平行转轴入射，经过透明屏幕，对准桶底处  的记号，测出透明屏幕至圆筒底部的距离、液面静止时高度。'; '改变圆桶转速6次，在透明屏幕上读出入射光与反射光点间距离d，则   ，求出  值'; '◆  验证抛物面焦距与转速的关系'; '     将毫米刻度垂直屏幕过转轴放入实验容器中央，激光束平行转轴入射至液面，后聚焦在屏幕上，可改变入射位置观察聚焦情况。'; '    改变圆桶转速6次，记录焦点位置。'; '◆  研究旋转液体表面成像规律    '; '    给激光器装上有箭头状光阑的帽盖，使其光束略有发散且在屏幕上成箭头状像。光束平行光轴在偏离光轴处射向旋转液体，经液面反射后，在水平屏幕上也留下了箭头。固定转速，上下移动屏幕的位置，观察像箭头的方向及大小变化。实验发现，屏幕在较低处时，入射光和反射光留下的箭头方向相同，随着屏幕逐渐上移，反射光留下的箭头越来越小直至成一光点，随后箭头反向且逐渐变大。也可以固定屏幕，改变转速，将会观察到类似的现象。'; '◆   测量液体粘滞系数'; '       装好实验装置、将张丝悬挂的圆柱体垂直置于液体中心，柱体上表面有一刻度线记号，低速旋转液体，稳定后柱面上刻度线偏一角度，用激光器和量角器测出偏转角。同一转速测三次，改变转速3次。'; ''; ''; '测量'; '1、张丝半径     2、 张丝长度'; '3、圆柱底面到外圆桶底面的距离4、圆柱高度     5、 圆柱半径  6、圆筒容器半径   '; ''; ''};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '方法1输入完成';
            app.DcmEditFieldLabel = uilabel(app.UIFigure);
            app.DcmEditFieldLabel.Text = '多次测量D/cm';
            app.DEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_1.Value = 10.05;
            app.DEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_2.Value = 10.052;
            app.DEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_3.Value = 10.046;
            app.DEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_4.Value = 10.05;
            app.DEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_5.Value = 10.052;
            app.DEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_6.Value = 10.05;
            app.n1EditFieldLabel = uilabel(app.UIFigure);
            app.n1EditFieldLabel.Text = '第一次的转速n1/(转/分)';
            app.n1EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.n1EditField_1.Value = 80;
            app.n1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.n1EditField_2.Value = 95;
            app.n1EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.n1EditField_3.Value = 110;
            app.n1EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.n1EditField_4.Value = 125;
            app.n1EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.n1EditField_5.Value = 145;
            app.n1EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.n1EditField_6.Value = 132;
            app.h1cmEditFieldLabel = uilabel(app.UIFigure);
            app.h1cmEditFieldLabel.Text = '高度差h1/cm';
            app.h1cmEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.h1cmEditField_1.Value = 0.75;
            app.h1cmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.h1cmEditField_2.Value = 1.07;
            app.h1cmEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.h1cmEditField_3.Value = 1.45;
            app.h1cmEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.h1cmEditField_4.ValueChangedFcn = createCallbackFcn(app, @h1cmEditField_4ValueChanged, true);
            app.h1cmEditField_4.Value = 1.86;
            app.h1cmEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.h1cmEditField_5.Value = 2.5;
            app.h1cmEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.h1cmEditField_6.Value = 2.09;
            app.Method1Label = uilabel(app.UIFigure);
            app.Method1Label.Text = 'Method1';
            app.Method3Label = uilabel(app.UIFigure);
            app.Method3Label.Text = '验证焦距关系+Method3：';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.H2cmEditFieldLabel = uilabel(app.UIFigure);
            app.H2cmEditFieldLabel.Text = '输入 H2（cm）';
            app.H2cmEditField = uieditfield(app.UIFigure, 'numeric');
            app.H2cmEditField.ValueChangedFcn = createCallbackFcn(app, @H2cmEditFieldValueChanged, true);
            app.H2cmEditField.Value = 8.9;
            app.h2cmEditFieldLabel = uilabel(app.UIFigure);
            app.h2cmEditFieldLabel.Text = '输入 h2（cm）';
            app.h2cmEditField = uieditfield(app.UIFigure, 'numeric');
            app.h2cmEditField.Value = 3.07;
            app.DEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_7.Value = 65;
            app.DEditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_8.Value = 80;
            app.DEditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_9.Value = 95;
            app.DEditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_10.Value = 110;
            app.DEditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_11.Value = 125;
            app.DEditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_12.Value = 45;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.n1EditFieldLabel_2 = uilabel(app.UIFigure);
            app.n1EditFieldLabel_2.Text = '转速n2/(转/分)';
            app.DEditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_13.Value = 22;
            app.DEditField_14 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_14.Value = 31;
            app.DEditField_15 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_15.Value = 46.5;
            app.DEditField_16 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_16.Value = 66;
            app.DEditField_17 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_17.Value = 54.5;
            app.DEditField_18 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_18.Value = 10.5;
            app.n1EditFieldLabel_3 = uilabel(app.UIFigure);
            app.n1EditFieldLabel_3.Text = 'BC间距d2/mm';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '方法2输入完成';
            app.Method2Label = uilabel(app.UIFigure);
            app.Method2Label.Text = 'Method2';
            app.g1cms2EditFieldLabel = uilabel(app.UIFigure);
            app.g1cms2EditFieldLabel.Text = '测得g1(cm/s^2)';
            app.g1cms2EditField = uieditfield(app.UIFigure, 'numeric');
            app.g1cms2EditField.ValueChangedFcn = createCallbackFcn(app, @g1cms2EditFieldValueChanged, true);
            app.g2cms2EditFieldLabel = uilabel(app.UIFigure);
            app.g2cms2EditFieldLabel.Text = '测得g2(cm/s^2)';
            app.g2cms2EditField = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_19 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_19.Value = 89;
            app.DEditField_20 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_20.Value = 65;
            app.DEditField_21 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_21.Value = 74;
            app.DEditField_22 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_22.Value = 90;
            app.DEditField_23 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_23.Value = 60;
            app.DEditField_24 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_24.Value = 72;
            app.DEditField_25 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_25.Value = 7.39;
            app.DEditField_26 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_26.Value = 11.85;
            app.DEditField_27 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_27.Value = 9.7;
            app.DEditField_28 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_28.Value = 6.9;
            app.DEditField_29 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_29.Value = 13.41;
            app.DEditField_30 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_30.Value = 10.12;
            app.DEditField_31 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_31.Value = 2.3;
            app.DEditField_32 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_32.Value = 2.29;
            app.DEditField_33 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_33.Value = 2.28;
            app.DEditField_34 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_34.Value = 2.2;
            app.DEditField_35 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_35.Value = 2.4;
            app.DEditField_36 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_36.Value = 2.45;
            app.n1EditFieldLabel_4 = uilabel(app.UIFigure);
            app.n1EditFieldLabel_4.Text = '转速n3/(转/分)';
            app.H2cmEditFieldLabel_2 = uilabel(app.UIFigure);
            app.H2cmEditFieldLabel_2.Text = '输入 H3（cm）';
            app.H2cmEditFieldLabel_3 = uilabel(app.UIFigure);
            app.H2cmEditFieldLabel_3.Text = '输入 h3（cm）';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '+-';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '+-';
            app.EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '方法3输入完成';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp6xzyt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end